classdef GUI_ROIGroups < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FLIMXROIGroupManagerUIFigure  matlab.ui.Figure
        AddROIButton                  matlab.ui.control.Button
        RemoveROIButton               matlab.ui.control.Button
        OKButton                      matlab.ui.control.Button
        StudyPanel                    matlab.ui.container.Panel
        StudyDropDown                 matlab.ui.control.DropDown
        ROIsinstudyListBoxLabel       matlab.ui.control.Label
        ROIsInStudyListBox            matlab.ui.control.ListBox
        ROIGroupPanel                 matlab.ui.container.Panel
        AddGroupButton                matlab.ui.control.Button
        DeleteGroupButton             matlab.ui.control.Button
        GroupDropDown                 matlab.ui.control.DropDown
        ROIsingroupListBoxLabel       matlab.ui.control.Label
        ROIsInGroupListBox            matlab.ui.control.ListBox
        NewGroupNameEditField         matlab.ui.control.EditField
    end

    
    properties (Access = private)
        FLIMXVis = []; %FLIMXVis object
        fdt = []; % FDTree object
    end
    
    properties(Dependent = true)
        currentStudy
        currentGroup
    end
    
    methods (Access = private)
        
        function setupGUI(app)
            %setup GUI elements
            allStudies = app.fdt.getAllStudyNames();
            app.StudyDropDown.Items = allStudies;
            [nrs, allROIStr] = app.fdt.getResultROITypes(app.currentStudy);
            roiGrps = app.fdt.getResultROIGroup(app.currentStudy,[]);
            if(isempty(roiGrps))
                %no groups yet
                app.ROIsInStudyListBox.Items = allROIStr;
                app.GroupDropDown.Enable = 'Off';
                app.DeleteGroupButton.Enable = 'Off';
                app.ROIsInGroupListBox.Items = {''};
                app.GroupDropDown.Items = {''};
                app.AddROIButton.Enable = 'Off';
                app.RemoveROIButton.Enable = 'Off';
            else
                app.GroupDropDown.Enable = 'On';
                app.DeleteGroupButton.Enable = 'On';
                app.GroupDropDown.Items = roiGrps(:,1);
                idx = find(strcmp(roiGrps(:,1),app.currentGroup),1,'first');
                curGrpItems = arrayfun(@ROICtrl.ROIType2ROIItem,roiGrps{idx,2},'UniformOutput',false);
                app.ROIsInGroupListBox.Items = curGrpItems;
                app.ROIsInStudyListBox.Items = setdiff(allROIStr,curGrpItems(:));
                app.AddROIButton.Enable = 'On';
                app.RemoveROIButton.Enable = 'On';
            end
        end
        
    end
    
    methods
        function out = get.currentStudy(app)
            %get current study name from GUI
            out = app.StudyDropDown.Value;
            %             out = '';
            %             str = app.StudyDropDown.Value;
            %             if(~isempty(str) && iscell(str))
            %                 out = str{app.StudyDropDown.Value};
            %             elseif(ischar(str))
            %                 out = str;
            %             end
        end
        
        function out = get.currentGroup(app)
            %get current study name from GUI
            out = app.GroupDropDown.Value;
        end
        
        function myStartupFcn(app,hFLIMXVis,currentStudy)
            %workaround for not-working builtin startup function
            app.FLIMXVis = hFLIMXVis;
            app.fdt = hFLIMXVis.fdt;
            app.checkVisWnd();
            %app.setupGUI();
            if(any(strcmp(app.StudyDropDown.Value,currentStudy)))
                app.StudyDropDown.Value = currentStudy;
            end
            %app.startupFcn(fdt,currentStudy)
        end
        
        function out = isOpenVisWnd(app)
            %check if figure is still open
            out = isgraphics(app.FLIMXROIGroupManagerUIFigure);
        end
        
        function checkVisWnd(app)
            %if not reopen
            if(~app.isOpenVisWnd())
                %no window - open one
                app.createComponents();
            end
            app.setupGUI();
            %uifigure(app.FLIMXROIGroupManagerUIFigure);
            app.FLIMXROIGroupManagerUIFigure.Visible = 'Off';
            app.FLIMXROIGroupManagerUIFigure.Visible = 'On';
        end %checkVisWnd
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hFLIMXVis, currentStudy)
            app.myStartupFcn(hFLIMXVis,currentStudy);
        end

        % Value changed function: StudyDropDown
        function StudyDropDownValueChanged(app, event)
            %study changed -> update GUI
            app.setupGUI();
        end

        % Button pushed function: AddGroupButton
        function AddGroupButtonPushed(app, event)
            %add a new group to the current study
            existingRoiGrps = app.fdt.getResultROIGroup(app.currentStudy,[]);
            new = app.NewGroupNameEditField.Value;
            if(isempty(new))
                return
            end
            if(~isempty(existingRoiGrps))
                idx = strcmp(existingRoiGrps,new);
                %group name already exists
                if(any(idx))
                    %propose a new unique group name
                    new = importFolderGUI.getNewSubjectName(new,existingRoiGrps);
                end
            end
            app.fdt.setResultROIGroup(app.currentStudy,new,{[]});
            app.NewGroupNameEditField.Value = '';
            app.setupGUI();
        end

        % Button pushed function: DeleteGroupButton
        function DeleteGroupButtonPushed(app, event)
            %remove the currently selected group
            existingRoiGrps = app.fdt.getResultROIGroup(app.currentStudy,[]);
            if(isempty(existingRoiGrps))
                return
            end
            idx = strcmp(existingRoiGrps,app.currentGroup);
            existingRoiGrps(idx,:) = [];
            app.fdt.setResultROIGroup(app.currentStudy,[],existingRoiGrps);
            app.setupGUI();
        end

        % Value changed function: NewGroupNameEditField
        function NewGroupNameEditFieldValueChanged(app, event)
            %check if input is a valid group name
            new = app.NewGroupNameEditField.Value;
            if(isempty(new))
                return
            end
            existingRoiGrps = app.fdt.getResultROIGroup(app.currentStudy,[]);
            if(~isempty(existingRoiGrps))
                idx = strcmp(existingRoiGrps,new);
                %group name already exists
                if(any(idx))
                    %propose a new unique group name
                    new = importFolderGUI.getNewSubjectName(new,existingRoiGrps);
                    app.NewGroupNameEditField.Value = new;
                end
            end
        end

        % Button pushed function: AddROIButton
        function AddROIButtonPushed(app, event)
            %add the selected ROIs (left side) to the current group
            rois = app.ROIsInStudyListBox.Value;
            ids = cellfun(@ROICtrl.ROIItem2ROIType,rois);
            roiGrps = app.fdt.getResultROIGroup(app.currentStudy,app.currentGroup);
            ids = unique([ids, roiGrps{:}]);
            app.fdt.setResultROIGroup(app.currentStudy,app.currentGroup,{ids});
            app.setupGUI();
        end

        % Button pushed function: RemoveROIButton
        function RemoveROIButtonPushed(app, event)
            %remove the selected ROIs (right side) from the current group
            grpROIs = app.ROIsInGroupListBox.Value;
            if(numel(grpROIs) == 1 && isempty(grpROIs{1,1}))
                return
            end
            idx = strcmp(app.ROIsInGroupListBox.Items,grpROIs);
            app.ROIsInGroupListBox.Items(idx) = [];
            ids = cellfun(@ROICtrl.ROIItem2ROIType,app.ROIsInGroupListBox.Items);
            app.fdt.setResultROIGroup(app.currentStudy,app.currentGroup,{ids});
            app.setupGUI();
        end

        % Close request function: FLIMXROIGroupManagerUIFigure
        function FLIMXROIGroupManagerUIFigureCloseRequest(app, event)
            %update FLIMXVis
            app.FLIMXVis.objHandles.ldo.sethfdSupp([]);
            app.FLIMXVis.objHandles.rdo.sethfdSupp([]);
            app.FLIMXVis.myStatsGroupComp.clearResults();
            app.FLIMXVis.objHandles.lROI.setupGUI();
            app.FLIMXVis.objHandles.rROI.setupGUI();
            app.FLIMXVis.objHandles.rdo.updatePlots();
            app.FLIMXVis.objHandles.ldo.updatePlots();
            app.FLIMXVis.myStatsDescr.setupGUI();
            %delete(app)
            delete(app.FLIMXROIGroupManagerUIFigure);            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.FLIMXROIGroupManagerUIFigureCloseRequest([]);
            
        end

        % Value changed function: GroupDropDown
        function GroupDropDownValueChanged(app, event)
            %value = app.GroupDropDown.Value;
            app.setupGUI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FLIMXROIGroupManagerUIFigure = uifigure('Visible', 'off');
            app.FLIMXROIGroupManagerUIFigure.Name = 'FLIMX ROI Group Manager';
            app.FLIMXROIGroupManagerUIFigure.CloseRequestFcn = createCallbackFcn(app, @FLIMXROIGroupManagerUIFigureCloseRequest, true);
            app.AddROIButton = uibutton(app.FLIMXROIGroupManagerUIFigure, 'push');
            app.AddROIButton.ButtonPushedFcn = createCallbackFcn(app, @AddROIButtonPushed, true);
            app.AddROIButton.Text = 'Add';
            app.RemoveROIButton = uibutton(app.FLIMXROIGroupManagerUIFigure, 'push');
            app.RemoveROIButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveROIButtonPushed, true);
            app.RemoveROIButton.Text = 'Remove';
            app.OKButton = uibutton(app.FLIMXROIGroupManagerUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.StudyPanel = uipanel(app.FLIMXROIGroupManagerUIFigure);
            app.StudyPanel.Title = 'Study';
            app.StudyDropDown = uidropdown(app.StudyPanel);
            app.StudyDropDown.ValueChangedFcn = createCallbackFcn(app, @StudyDropDownValueChanged, true);
            app.StudyDropDown.Tooltip = {'Current study'};
            app.ROIsinstudyListBoxLabel = uilabel(app.StudyPanel);
            app.ROIsinstudyListBoxLabel.Text = 'ROIs in study';
            app.ROIsInStudyListBox = uilistbox(app.StudyPanel);
            app.ROIsInStudyListBox.Multiselect = 'on';
            app.ROIsInStudyListBox.Value = {'Item 1'};
            app.ROIGroupPanel = uipanel(app.FLIMXROIGroupManagerUIFigure);
            app.ROIGroupPanel.Title = 'ROI Group';
            app.AddGroupButton = uibutton(app.ROIGroupPanel, 'push');
            app.AddGroupButton.ButtonPushedFcn = createCallbackFcn(app, @AddGroupButtonPushed, true);
            app.AddGroupButton.Text = 'Add';
            app.DeleteGroupButton = uibutton(app.ROIGroupPanel, 'push');
            app.DeleteGroupButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteGroupButtonPushed, true);
            app.DeleteGroupButton.Text = 'Delete';
            app.GroupDropDown = uidropdown(app.ROIGroupPanel);
            app.GroupDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupDropDownValueChanged, true);
            app.ROIsingroupListBoxLabel = uilabel(app.ROIGroupPanel);
            app.ROIsingroupListBoxLabel.Text = 'ROIs in group';
            app.ROIsInGroupListBox = uilistbox(app.ROIGroupPanel);
            app.ROIsInGroupListBox.Multiselect = 'on';
            app.ROIsInGroupListBox.Value = {'Item 1'};
            app.NewGroupNameEditField = uieditfield(app.ROIGroupPanel, 'text');
            app.NewGroupNameEditField.ValueChangedFcn = createCallbackFcn(app, @NewGroupNameEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.FLIMXROIGroupManagerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_ROIGroups(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FLIMXROIGroupManagerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FLIMXROIGroupManagerUIFigure)
        end
    end
end