classdef GUI_ROI_Options < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FLIMXVisROIOptionsUIFigure      matlab.ui.Figure
        CancelButton                    matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        AutomaticROIGenerationPanel     matlab.ui.container.Panel
        otherPanel                      matlab.ui.container.Panel
        UpperquantileEditField          matlab.ui.control.NumericEditField
        UpperquantileLabel              matlab.ui.control.Label
        LowerquantileEditField          matlab.ui.control.NumericEditField
        LowerquantileLabel              matlab.ui.control.Label
        spectralPanel                   matlab.ui.container.Panel
        UpperquantileSpectralEditField  matlab.ui.control.NumericEditField
        UpperquantileEditField_3Label   matlab.ui.control.Label
        LowerquantileSpectralEditField  matlab.ui.control.NumericEditField
        LowerquantileEditField_3Label   matlab.ui.control.Label
        TauMeanPanel                    matlab.ui.container.Panel
        UpperquantileTauMeanEditField   matlab.ui.control.NumericEditField
        UpperquantileEditField_2Label   matlab.ui.control.Label
        LowerquantileTauMeanEditField   matlab.ui.control.NumericEditField
        LowerquantileEditField_2Label   matlab.ui.control.Label
        Rectangle3Label                 matlab.ui.control.Label
        ROIVicinityParametersPanel      matlab.ui.container.Panel
        VicinitydiameterEditField       matlab.ui.control.NumericEditField
        VicinitydiameterEditField_2Label  matlab.ui.control.Label
        VicinitydistanceEditField       matlab.ui.control.NumericEditField
        VicinitydistanceEditField_2Label  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ROIParams % struct with ROI parameters
        dirtyFlags = false(2,1); % 1: vicinity parameters; 2: rectangle quantile
        FLIMXVis % handle to FLIMXVisGUI
        fdt % handle to FDTree
    end
    
    methods (Access = private)
        
        function myStartupFcn(app, hFLIMXVis)
            %workaround for not-working builtin startup function
            app.FLIMXVis = hFLIMXVis;
            app.fdt = hFLIMXVis.fdt;
            app.ROIParams = hFLIMXVis.FLIMXObj.paramMgr.getParamSection('region_of_interest');
            app.checkVisWnd();
            app.setupGUI();
        end
        
        
        function setupGUI(app)
            %update GUI components
            app.VicinitydistanceEditField.Value = app.ROIParams.vicinityDistance;
            app.VicinitydiameterEditField.Value = app.ROIParams.vicinityDiameter;
            app.LowerquantileEditField.Value = app.ROIParams.rectangle3Quantil(1);
            app.UpperquantileEditField.Value = app.ROIParams.rectangle3Quantil(2);
            app.LowerquantileSpectralEditField.Value = app.ROIParams.rectangle3Quantil_spectral(1);
            app.UpperquantileSpectralEditField.Value = app.ROIParams.rectangle3Quantil_spectral(2);
            app.LowerquantileTauMeanEditField.Value = app.ROIParams.rectangle3Quantil_TauMean1(1);
            app.UpperquantileTauMeanEditField.Value = app.ROIParams.rectangle3Quantil_TauMean1(2);
        end
    end
    
    methods (Access = public)

        function out = isOpenVisWnd(app)
            %check if figure is still open
            out = isgraphics(app.FLIMXVisROIOptionsUIFigure);
        end
        
        function checkVisWnd(app)
            %if not reopen
            if(~app.isOpenVisWnd())
                %no window - open one
                app.createComponents();
            end
            app.setupGUI();
            app.FLIMXVisROIOptionsUIFigure.Visible = 'Off';
            app.FLIMXVisROIOptionsUIFigure.Visible = 'On';
        end %checkVisWnd
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hFLIMXVis)
            app.myStartupFcn(hFLIMXVis);
        end

        % Value changed function: VicinitydistanceEditField
        function VicinitydistanceEditFieldValueChanged(app, event)
            app.ROIParams.vicinityDistance = app.VicinitydistanceEditField.Value;
            app.dirtyFlags(1) = true;
        end

        % Value changed function: VicinitydiameterEditField
        function VicinitydiameterEditFieldValueChanged(app, event)
            app.ROIParams.vicinityDiameter = app.VicinitydiameterEditField.Value;
            app.dirtyFlags(1) = true;
        end

        % Value changed function: LowerquantileEditField
        function LowerquantileEditFieldValueChanged(app, event)
            value = min(app.LowerquantileEditField.Value,app.UpperquantileEditField.Value-1);
            app.ROIParams.rectangle3Quantil(1) = value;
            app.LowerquantileEditField.Value = value;
            app.dirtyFlags(2) = true;
        end

        % Value changed function: UpperquantileEditField
        function UpperquantileEditFieldValueChanged(app, event)
            value = max(app.UpperquantileEditField.Value,app.LowerquantileEditField.Value+1);
            app.ROIParams.rectangle3Quantil(2) = value;
            app.UpperquantileEditField.Value = value;
            app.dirtyFlags(2) = true;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            if(any(app.dirtyFlags))
                app.FLIMXVisROIOptionsUIFigure.Pointer = 'watch';
                drawnow
                app.FLIMXVis.FLIMXObj.paramMgr.setParamSection('region_of_interest',app.ROIParams);
                app.FLIMXVis.objHandles.ldo.sethfdSupp([]);
                app.FLIMXVis.objHandles.rdo.sethfdSupp([]);
                app.FLIMXVis.myStatsGroupComp.clearResults();
                app.fdt.clearAllCIs(''); %can be more efficient
                app.fdt.clearAllMVGroupIs();
                app.FLIMXVis.objHandles.lROI.setupGUI();
                app.FLIMXVis.objHandles.rROI.setupGUI();
                app.FLIMXVis.objHandles.lROI.updateGUI([]);
                app.FLIMXVis.objHandles.rROI.updateGUI([]);
                app.FLIMXVis.objHandles.rdo.updatePlots();
                app.FLIMXVis.objHandles.ldo.updatePlots();
                app.FLIMXVis.myStatsDescr.setupGUI();
                app.FLIMXVisROIOptionsUIFigure.Pointer = 'arrow';
                drawnow
            end
            delete(app.FLIMXVisROIOptionsUIFigure);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            FLIMXVisROIOptionsUIFigureCloseRequest(app, event)
        end

        % Close request function: FLIMXVisROIOptionsUIFigure
        function FLIMXVisROIOptionsUIFigureCloseRequest(app, event)
            delete(app)            
        end

        % Value changed function: LowerquantileTauMeanEditField
        function LowerquantileTauMeanEditFieldValueChanged(app, event)
            value = min(app.LowerquantileTauMeanEditField.Value,app.UpperquantileTauMeanEditField.Value-1);
            app.ROIParams.rectangle3Quantil_TauMean1(1) = value;
            app.LowerquantileTauMeanEditField.Value = value;
            app.dirtyFlags(2) = true;            
        end

        % Value changed function: UpperquantileTauMeanEditField
        function UpperquantileTauMeanEditFieldValueChanged(app, event)
            value = max(app.UpperquantileTauMeanEditField.Value,app.LowerquantileTauMeanEditField.Value+1);
            app.ROIParams.rectangle3Quantil_TauMean1(2) = value;
            app.UpperquantileTauMeanEditField.Value = value;
            app.dirtyFlags(2) = true;
        end

        % Value changed function: LowerquantileSpectralEditField
        function LowerquantileSpectralEditFieldValueChanged(app, event)
            value = min(app.LowerquantileSpectralEditField.Value,app.UpperquantileSpectralEditField.Value-1);
            app.ROIParams.rectangle3Quantil_spectral(1) = value;
            app.LowerquantileSpectralEditField.Value = value;
            app.dirtyFlags(2) = true;
        end

        % Value changed function: UpperquantileSpectralEditField
        function UpperquantileSpectralEditFieldValueChanged(app, event)
            value = max(app.UpperquantileSpectralEditField.Value,app.LowerquantileSpectralEditField.Value+1);
            app.ROIParams.rectangle3Quantil_spectral(2) = value;
            app.UpperquantileSpectralEditField.Value = value;
            app.dirtyFlags(2) = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FLIMXVisROIOptionsUIFigure = uifigure('Visible', 'off');
            app.FLIMXVisROIOptionsUIFigure.Name = 'FLIMXVis ROI Options';
            app.FLIMXVisROIOptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @FLIMXVisROIOptionsUIFigureCloseRequest, true);
            app.ROIVicinityParametersPanel = uipanel(app.FLIMXVisROIOptionsUIFigure);
            app.ROIVicinityParametersPanel.Title = 'ROI Vicinity Parameters';
            app.VicinitydistanceEditField_2Label = uilabel(app.ROIVicinityParametersPanel);
            app.VicinitydistanceEditField_2Label.Text = 'Vicinity distance';
            app.VicinitydistanceEditField = uieditfield(app.ROIVicinityParametersPanel, 'numeric');
            app.VicinitydistanceEditField.Limits = [0 1000];
            app.VicinitydistanceEditField.RoundFractionalValues = 'on';
            app.VicinitydistanceEditField.ValueDisplayFormat = '%.0f';
            app.VicinitydistanceEditField.ValueChangedFcn = createCallbackFcn(app, @VicinitydistanceEditFieldValueChanged, true);
            app.VicinitydiameterEditField_2Label = uilabel(app.ROIVicinityParametersPanel);
            app.VicinitydiameterEditField_2Label.Text = 'Vicinity diameter';
            app.VicinitydiameterEditField = uieditfield(app.ROIVicinityParametersPanel, 'numeric');
            app.VicinitydiameterEditField.Limits = [0 1000];
            app.VicinitydiameterEditField.RoundFractionalValues = 'on';
            app.VicinitydiameterEditField.ValueDisplayFormat = '%.0f';
            app.VicinitydiameterEditField.ValueChangedFcn = createCallbackFcn(app, @VicinitydiameterEditFieldValueChanged, true);
            app.AutomaticROIGenerationPanel = uipanel(app.FLIMXVisROIOptionsUIFigure);
            app.AutomaticROIGenerationPanel.Title = 'Automatic ROI Generation';
            app.Rectangle3Label = uilabel(app.AutomaticROIGenerationPanel);
            app.Rectangle3Label.Text = 'Rectangle #3';
            app.TauMeanPanel = uipanel(app.AutomaticROIGenerationPanel);
            app.TauMeanPanel.Title = 'TauMean';
            app.LowerquantileEditField_2Label = uilabel(app.TauMeanPanel);
            app.LowerquantileEditField_2Label.Text = 'Lower quantile (%)';
            app.LowerquantileTauMeanEditField = uieditfield(app.TauMeanPanel, 'numeric');
            app.LowerquantileTauMeanEditField.Limits = [0 98];
            app.LowerquantileTauMeanEditField.ValueDisplayFormat = '%.1f';
            app.LowerquantileTauMeanEditField.ValueChangedFcn = createCallbackFcn(app, @LowerquantileTauMeanEditFieldValueChanged, true);
            app.LowerquantileTauMeanEditField.Value = 2;
            app.UpperquantileEditField_2Label = uilabel(app.TauMeanPanel);
            app.UpperquantileEditField_2Label.Text = {'Upper quantile (%)'; ''};
            app.UpperquantileTauMeanEditField = uieditfield(app.TauMeanPanel, 'numeric');
            app.UpperquantileTauMeanEditField.Limits = [2 100];
            app.UpperquantileTauMeanEditField.ValueDisplayFormat = '%.1f';
            app.UpperquantileTauMeanEditField.ValueChangedFcn = createCallbackFcn(app, @UpperquantileTauMeanEditFieldValueChanged, true);
            app.UpperquantileTauMeanEditField.Value = 98;
            app.spectralPanel = uipanel(app.AutomaticROIGenerationPanel);
            app.spectralPanel.Title = 'spectral';
            app.LowerquantileEditField_3Label = uilabel(app.spectralPanel);
            app.LowerquantileEditField_3Label.Text = 'Lower quantile (%)';
            app.LowerquantileSpectralEditField = uieditfield(app.spectralPanel, 'numeric');
            app.LowerquantileSpectralEditField.Limits = [0 98];
            app.LowerquantileSpectralEditField.ValueDisplayFormat = '%.1f';
            app.LowerquantileSpectralEditField.ValueChangedFcn = createCallbackFcn(app, @LowerquantileSpectralEditFieldValueChanged, true);
            app.LowerquantileSpectralEditField.Value = 2;
            app.UpperquantileEditField_3Label = uilabel(app.spectralPanel);
            app.UpperquantileEditField_3Label.Text = {'Upper quantile (%)'; ''};
            app.UpperquantileSpectralEditField = uieditfield(app.spectralPanel, 'numeric');
            app.UpperquantileSpectralEditField.Limits = [2 100];
            app.UpperquantileSpectralEditField.ValueDisplayFormat = '%.1f';
            app.UpperquantileSpectralEditField.ValueChangedFcn = createCallbackFcn(app, @UpperquantileSpectralEditFieldValueChanged, true);
            app.UpperquantileSpectralEditField.Value = 98;
            app.otherPanel = uipanel(app.AutomaticROIGenerationPanel);
            app.otherPanel.Title = 'other';
            app.LowerquantileLabel = uilabel(app.otherPanel);
            app.LowerquantileLabel.Text = 'Lower quantile (%)';
            app.LowerquantileEditField = uieditfield(app.otherPanel, 'numeric');
            app.LowerquantileEditField.Limits = [0 98];
            app.LowerquantileEditField.ValueDisplayFormat = '%.1f';
            app.LowerquantileEditField.ValueChangedFcn = createCallbackFcn(app, @LowerquantileEditFieldValueChanged, true);
            app.LowerquantileEditField.Value = 2;
            app.UpperquantileLabel = uilabel(app.otherPanel);
            app.UpperquantileLabel.Text = {'Upper quantile (%)'; ''};
            app.UpperquantileEditField = uieditfield(app.otherPanel, 'numeric');
            app.UpperquantileEditField.Limits = [2 100];
            app.UpperquantileEditField.ValueDisplayFormat = '%.1f';
            app.UpperquantileEditField.ValueChangedFcn = createCallbackFcn(app, @UpperquantileEditFieldValueChanged, true);
            app.UpperquantileEditField.Value = 98;
            app.OKButton = uibutton(app.FLIMXVisROIOptionsUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.FLIMXVisROIOptionsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.FLIMXVisROIOptionsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_ROI_Options(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FLIMXVisROIOptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FLIMXVisROIOptionsUIFigure)
        end
    end
end