classdef StatsMultiGroupComparison < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FLIMXStatsMultiGroupUIFigure  matlab.ui.Figure
        ButtonDeleteAllGroups         matlab.ui.control.Button
        workingLamp                   matlab.ui.control.Image
        TestDropDown                  matlab.ui.control.DropDown
        TestDropDownLabel             matlab.ui.control.Label
        workingLabel                  matlab.ui.control.Label
        SignificancelevelEditFieldPercent  matlab.ui.control.Label
        SignificancelevelEditFieldAlpha  matlab.ui.control.Label
        SignificancelevelEditField    matlab.ui.control.NumericEditField
        SignificancelevelEditFieldLabel  matlab.ui.control.Label
        ButtonDeleteGroup             matlab.ui.control.Button
        DropDownROIVicinity           matlab.ui.control.DropDown
        DropDownROISubtype            matlab.ui.control.DropDown
        TableExtAnalysis              matlab.ui.control.Table
        TableGroups                   matlab.ui.control.Table
        ButtonAddGroup                matlab.ui.control.Button
        TreeFLIMItems                 matlab.ui.container.CheckBoxTree
        DropDownROIType               matlab.ui.control.DropDown
        DropDownDataSource            matlab.ui.control.DropDown
        DatasourceDropDownLabel       matlab.ui.control.Label
        ButtonTest                    matlab.ui.control.Button
        DropDownCondition             matlab.ui.control.DropDown
        DropDownStudy                 matlab.ui.control.DropDown
        AxesGrpPlots                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        FLIMXVis = []; %FLIMXVis object
        fdt = []; % FDTree object
        FLIMItemNodes = cell(0,0);
        selectedFLIMItems = cell(0,0);
        chNodes = cell(0,0);
        grpDefs = cell(0,0);
        selectedGrps = [];
        manovaDummyFig = [];
    end

    properties(Dependent = true)
        alpha
        currentStudy
        currentCondition
        %currentChannel
        currentROIType
    end

    methods (Access = private)
        function setupGUI(app)
            %setup GUI elements
            allStudies = app.fdt.getAllStudyNames();
            app.DropDownStudy.Items = allStudies;
            app.DropDownCondition.Items = app.fdt.getStudyConditionsStr(app.currentStudy);

            chStr = [];
            coStr = 'param';
            allSubjects = app.fdt.getAllSubjectNames(app.currentStudy,app.currentCondition);
            if(~isempty(allSubjects))
                chStr = app.fdt.getChStr(app.currentStudy,allSubjects{1});
                if(~isempty(chStr))
                    ch = chStr{1};
                    ch = str2double(ch(isstrprop(ch, 'digit')));
                    coStr = app.fdt.getChObjStr(app.currentStudy,allSubjects{1},ch);
                end
            end
            %delete old FLIM items
            for i = 1:length(app.FLIMItemNodes)
                try
                    delete(app.FLIMItemNodes{i})
                catch ME

                end
            end
            app.FLIMItemNodes = cell(0,0);
            app.chNodes = cell(0,0);
            if(iscell(coStr))
                cnCnt = 1;
                for i = 1:length(coStr)
                    app.FLIMItemNodes{i,1} = uitreenode(app.TreeFLIMItems,'Text',coStr{i},'NodeData',[]);
                    for j = 1:length(chStr)
                        app.chNodes{cnCnt,1} = uitreenode(app.FLIMItemNodes{i},'Text',chStr{j},'NodeData',[]);
                        cnCnt = cnCnt+1;
                    end
                end
                %             app.TreeFLIMItems.expand();
            end

            app.TableGroups.Data = [];
            app.TableExtAnalysis.Data = [];

            %             cla(app.AxesGrpHistograms);
            %             axis(app.AxesGrpHistograms,'off');
        end

        function updateGUI(app)
            %update GUI elements to current values
            allSubjects = app.fdt.getAllSubjectNames(app.currentStudy,app.currentCondition);
            allROIStr = {ROICtrl.ROIType2ROIItem(0)};
            if(~isempty(allSubjects))
                allROT = app.fdt.getResultROITypes(app.currentStudy);
                if(isempty(allROT))
                    allROT = ROICtrl.getDefaultROIStruct();
                end
                allROIStr = arrayfun(@ROICtrl.ROIType2ROIItem,[0;allROT(:,1,1)],'UniformOutput',false);
            end
            roiGrps = app.fdt.getResultROIGroup(app.currentStudy,[]);
            if(~isempty(roiGrps) && ~isempty(roiGrps{1,1}))
                allROIStr = [allROIStr; sprintfc('Group: %s',string(roiGrps(:,1)))];
            end
            %setup ROI controls
            app.DropDownROIType.Items = allROIStr;
            rt = app.currentROIType;
            if(rt == 0 || rt > 2000 || rt < 0)
                flag = 'off';
            else
                flag = 'on';
            end
            app.DropDownROISubtype.Visible = flag;
            if(rt > 1000 || rt < 0)
                flag = 'on';
            end
            app.DropDownROIVicinity.Visible = flag;
            %groups table
            %convert group definitions to readable strings
            td = cell(0,5);
            nGrps = size(app.grpDefs,1);
            for i = 1:nGrps
                td{i,1} = i; %group number
                td(i,2) = app.grpDefs(i,1); %study
                td(i,3) = app.grpDefs(i,2); %condition
                fiStr = '';
                tmp = app.grpDefs{i,3};
                for j = 1:size(tmp,1)
                    fiStr = [fiStr sprintf('%s Ch %d; ',tmp{j,1},tmp{j,2})];
                end
                fiStr(end-1:end) = '';
                td{i,4} = fiStr;
                tmp = app.grpDefs{i,4};
                if(tmp(1) < 0)
                    %ROI Group
                    rStr = sprintf('Group: %s',string(roiGrps(abs(tmp(1)),1)));
                else
                    rStr = ROICtrl.ROIType2ROIItem(tmp(1));
                end
                if(tmp(2) > 0)
                    rStr = [rStr ' ' char(app.DropDownROISubtype.Items(tmp(2)))];
                end
                if(tmp(3) > 0)
                    rStr = [rStr ' ' char(app.DropDownROIVicinity.Items(tmp(3)))];
                end
                td{i,5} = rStr;
            end
            app.TableGroups.Data = td;

            if(nGrps > 0)
                if(nGrps == 1 && size(app.grpDefs{1,3},1) > 1 || nGrps > 1 && size(app.grpDefs{1,3},1) == 1)
                    app.TestDropDown.Enable = 'On';
                    app.SignificancelevelEditField.Enable = 'On';
                    app.DropDownDataSource.Enable = 'On';
                    app.ButtonTest.Enable = 'On';
                    app.TestDropDown.Items = [{'1-Way ANOVA'},{'Kruskal-Wallis'}]; %[{'1-Way ANOVA'},{'2-Way ANOVA'},{'n-Way ANOVA'},{'Kruskal-Wallis'}]
                elseif(nGrps > 1)
                    app.TestDropDown.Enable = 'On';
                    app.SignificancelevelEditField.Enable = 'On';
                    app.DropDownDataSource.Enable = 'On';
                    app.ButtonTest.Enable = 'On';
                    app.TestDropDown.Items = [{'1-Way MANOVA'}];
                    if(length(unique(app.grpDefs(:,1))) == 1 && length(unique(app.grpDefs(:,2))) == 1 )
                        %all studies and conditions are identical
                        app.DropDownDataSource.Items = [{'pixel value(s)'} ,{'mean'},{'median'},{'test for each subject (pixel values)'}];
                    else
                        app.DropDownDataSource.Items = [{'pixel value(s)'} ,{'mean'},{'median'}];
                    end
                end
            else
                app.DropDownDataSource.Enable = 'Off';
                app.ButtonTest.Enable = 'Off';
                app.TestDropDown.Enable = 'Off';
                app.SignificancelevelEditField.Enable = 'Off';
            end
            app.TableExtAnalysis.Data = {};
            app.workingLamp.Visible = 'Off';
            cla(app.AxesGrpPlots);
        end

        function [tableData] = computeTest(app,grpData,tableData)
            %compute statistics test
            switch app.TestDropDown.Value
                case {'1-Way ANOVA'}
                    try
                        [p,tbl,stats] = anova1(grpData(:,1:end-1),[],'off');
                    catch ME
                        tableData{end+1,1} = 'Error';
                        tableData{end,2} = ME.message;
                        return
                    end
                    tableData{end+1,1} = 'Decision';
                    tableData{end,2} = num2str(p < app.alpha);
                    tableData{end+1,1} = 'p Value';
                    tableData{end,2} = num2str(p,'%0.3f');
                case {'2-Way ANOVA'}
                    try
                        [p,tbl,stats] = anova2(grpData(:,1:end-1),[],'off');
                    catch ME
                        tableData{end+1,1} = 'Error';
                        tableData{end,2} = ME.message;
                        return
                    end
                    tableData{end+1,1} = 'Decision';
                    tableData{end,2} = num2str(p < app.alpha);
                    tableData{end+1,1} = 'p Value';
                    tableData{end,2} = num2str(p,'%0.3f');
                case {'n-Way ANOVA'}
                    try
                        [p,tbl,stats] = anovan(grpData(:,1:end-1),[],'off');
                    catch ME
                        tableData{end+1,1} = 'Error';
                        tableData{end,2} = ME.message;
                        return
                    end
                    tableData{end+1,1} = 'Decision';
                    tableData{end,2} = num2str(p < app.alpha);
                    tableData{end+1,1} = 'p Value';
                    tableData{end,2} = num2str(p,'%0.3f');
                case {'Kruskal-Wallis'}
                    try
                        [p,tbl,stats] = kruskalwallis(grpData(:,1:end-1),[],'off');
                    catch ME
                        tableData{end+1,1} = 'Error';
                        tableData{end,2} = ME.message;
                        return
                    end
                    tableData{end+1,1} = 'Decision';
                    tableData{end,2} = num2str(p < app.alpha);
                    tableData{end+1,1} = 'p Value';
                    tableData{end,2} = num2str(p,'%0.3f');
                case {'1-Way MANOVA'}
                    if(isempty(app.manovaDummyFig) || ~isgraphics(app.manovaDummyFig))
                        app.manovaDummyFig = figure;
                    end
                    figure(app.manovaDummyFig);
                    try                        
                        [d,p,stats] = manova1(grpData(:,1:end-1),grpData(:,end),app.alpha);                        
                    catch ME
                        tableData{end+1,1} = 'Error';
                        tableData{end,2} = ME.message;
                        return
                    end
                    tableData{end+1,1} = 'Decision';
                    tableData{end,2} = num2str(d);
                    tableData{end+1,1} = 'p Value(s)';
                    pStr = '';
                    for pi = 1:length(p)
                        pStr = [pStr sprintf('dim%d: %0.3f  ',pi,p(pi))];
                    end
                    tableData{end,2} = pStr;
                case {'2-Way MANOVA'}
                    if(isempty(app.manovaDummyFig) || ~isgraphics(app.manovaDummyFig))
                        app.manovaDummyFig = figure;
                    end
                    figure(app.manovaDummyFig);
                    try                        
                        [d,p,stats] = manova1(grpData(:,1:end-1),grpData(:,end),app.alpha);                        
                    catch ME
                        tableData{end+1,1} = 'Error';
                        tableData{end,2} = ME.message;
                        return
                    end
                    tableData{end+1,1} = 'Decision';
                    tableData{end,2} = num2str(d);
                    tableData{end+1,1} = 'p Value(s)';
                    pStr = '';
                    for pi = 1:length(p)
                        pStr = [pStr sprintf('dim%d: %0.3f  ',pi,p(pi))];
                    end
                    tableData{end,2} = pStr;
            end
        end

        %         function [centers, histograms, histogramDiff] = makeHistograms(app)
        %             %make histogram tables
        %             %first get the centers for all groups
        %             centers = []; equalCWFlag = true;
        %             for i = 1:size(app.grpDefs,1)
        %                 ct = app.fdt.getStudyHistogram(this.study1,this.condition1,this.ch1,this.dType1,this.id1,this.ROIType,this.ROISubType,this.ROIVicinityFlag);
        %                 [centers, eft] = StatsMultiGroupComparison.merge2HistCenters(centers,ct);
        %                 equalCWFlag = equalCWFlag && eft;
        %             end
        %             %c1 = app.fdt.getStudyHistogram(this.study1,this.condition1,this.ch1,this.dType1,this.id1,this.ROIType,this.ROISubType,this.ROIVicinityFlag);
        %             %c2 = app.fdt.getStudyHistogram(this.study2,this.condition2,this.ch2,this.dType2,this.id2,this.ROIType,this.ROISubType,this.ROIVicinityFlag);
        %
        %
        %             %now insert each histogram table at the correct position
        %             for j = 1:2
        %                 [grpCenters, histMerge, histTable, colDescr] = this.visObj.fdt.getStudyHistogram(eval(sprintf('this.study%d',j)),eval(sprintf('this.condition%d',j)),eval(sprintf('this.ch%d',j)),eval(sprintf('this.dType%d',j)),eval(sprintf('this.id%d',j)),this.ROIType,this.ROISubType,this.ROIVicinityFlag);
        %                 if(~isempty(grpCenters))
        %                     tab = zeros(length(colDescr),length(centers)); %subjects x classes
        %                     if(get(this.visHandles.checkSubstractRowMax,'Value'))
        %                         histTable = bsxfun(@minus,histTable,max(histTable,[],2,'omitnan') .* str2double(get(this.visHandles.editSubstractRowMax,'String'))/100);
        %                         histTable(histTable < 0) = 0;
        %                     end
        %                     [~, idx] = intersect(centers,grpCenters);
        %                     tab(:,idx) = histTable;
        %                     this.histograms(j) = {tab};
        %                     this.grpNames(j) = {colDescr};
        %                     %normalized sum of group histogram
        %                     tab = sum(tab,1);
        %                     %summenhist durch anzahl probanden teilen = mittleres
        %                     switch this.HistSumScale
        %                         case 1 %maximum
        %                             tab = tab./max(tab(:),[],'omitnan');
        %                         case 2 %number of subjects
        %                             tab = tab./length(colDescr);
        %                     end
        %                     this.histogramSum(j) = {tab};
        %                 else
        %                     %the histogram for this group is empty, clear all related info
        %                     this.histograms(j) = {[]};
        %                     this.grpNames(j) = {''};
        %                     this.histogramSum(j) = {[]};
        %                 end
        %             end
        %             if(isempty(this.histograms{1}) || isempty(this.histograms{2}))
        %                 %at least one group is empty, we can't compute the differences
        %                 this.histCenters = [];
        %                 this.histogramDiff = [];
        %             else
        %                 this.histCenters = centers;
        %                 if(equalCWFlag)
        %                     this.histogramDiff = this.histogramSum{1} - this.histogramSum{2};
        %                 else
        %                     idx = this.histogramSum{1} ~= 0 & this.histogramSum{2} ~= 0;
        %                     this.histogramDiff = zeros(1,length(centers));
        %                     this.histogramDiff(idx) = this.histogramSum{1}(idx) - this.histogramSum{2}(idx);
        %                 end
        %             end
        %         end


        function deleteAllGroups(app,srcFigure,event)
            %delete all group definitons; helper function for uiconfirm
            if(event.SelectedOption == "OK")
                app.grpDefs = cell(0,0);
                app.selectedGrps = [];
                app.updateGUI();
            end
        end
    end

    methods
        function out = get.alpha(app)
            %get current alpha value
            out = abs(app.SignificancelevelEditField.Value)/100;
        end

        function out = get.currentStudy(app)
            %get current study name from GUI
            out = app.DropDownStudy.Value;
            %             out = '';
            %             str = app.DropDownStudy.Value;
            %             if(~isempty(str) && iscell(str))
            %                 out = str{app.DropDownStudy.Value};
            %             elseif(ischar(str))
            %                 out = str;
            %             end
        end

        function out = get.currentCondition(app)
            %get current condition from GUI
            out = app.DropDownCondition.Value;
        end

        %         function out = get.currentChannel(app)
        %             %get current channel from GUI
        %             out = app.DropDownChannel.Value;
        %             out = str2double(out(isstrprop(out, 'digit')));
        %         end

        function out = get.currentROIType(app)
            %get current ROI from GUI
            str = app.DropDownROIType.Value;
            if(strncmp(str,'Group: ',7))
                %this is a group
                idx = strncmp(app.DropDownROIType.Items,'Group: ',7);
                grps = app.DropDownROIType.Items(idx);
                out = -1*find(strcmp(grps,str),1,'first');
            else
                out = ROICtrl.ROIItem2ROIType(str);
            end
        end

        function myStartupFcn(app,hFLIMXVis,currentStudy)
            %workaround for not-working builtin startup function
            app.FLIMXVis = hFLIMXVis;
            app.fdt = hFLIMXVis.fdt;
            app.checkVisWnd();
            if(any(strcmp(app.DropDownStudy.Items,currentStudy)))
                app.DropDownStudy.Value = currentStudy;
            end
            app.setupGUI();
            app.updateGUI();
            %app.startupFcn(fdt,currentStudy)
        end

        function out = isOpenVisWnd(app)
            %check if figure is still open
            out = isgraphics(app.FLIMXStatsMultiGroupUIFigure);
        end

        function checkVisWnd(app)
            %if not reopen
            if(~app.isOpenVisWnd())
                %no window - open one
                app.createComponents();
            end
            app.setupGUI();
            app.updateGUI();
            %uifigure(app.FLIMXROIGroupManagerUIFigure);
            app.FLIMXStatsMultiGroupUIFigure.Visible = 'Off';
            app.FLIMXStatsMultiGroupUIFigure.Visible = 'On';
        end %checkVisWnd
    end

    methods(Static)

        function [centers, equalCWFlag] = merge2HistCenters(c1,c2)
            %merge centers of 2 histograms
            if(~isempty(c1) && length(c1) >= 2)
                cw1 = c1(2) - c1(1);
            elseif(~isempty(c1) && length(c1) == 1)
                cw1 = c1(1);
            else
                cw1 = [];
            end
            if(~isempty(c2) && length(c2) >= 2)
                cw2 = c2(2) - c2(1);
            elseif(~isempty(c2) && length(c2) == 1)
                cw2 = c2(1);
            else
                cw2 = [];
            end
            if(cw1 == cw2)
                centers = unique([c1, c2]);
                equalCWFlag = true;
            elseif(isempty(cw1))
                centers = c2;
                equalCWFlag = false;
            elseif(isempty(cw2))
                centers = c1;
                equalCWFlag = false;
            else
                cw = max(0.1,min(cw1,cw2));
                centers = min(c1(1),c2(1)) : cw : max(c1(end),c2(end));
                equalCWFlag = false;
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, hFLIMXVis, currentStudy)
            app.myStartupFcn(hFLIMXVis,currentStudy);
        end

        % Value changed function: DropDownStudy
        function DropDownStudyValueChanged(app, event)
            %study changed -> update GUI
            app.setupGUI();
            app.updateGUI();
        end

        % Callback function: TreeFLIMItems
        function TreeFLIMItemsCheckedNodesChanged(app, event)
            checkedNodes = app.TreeFLIMItems.CheckedNodes;
            if(length(checkedNodes) < 1)
                app.selectedFLIMItems = cell(0,0);
                return
            end
            tmp = cell(0,2);
            cnCnt = 1;
            for i = 1:length(checkedNodes)
                if(checkedNodes(i).Parent ~= app.TreeFLIMItems)
                    tmp{cnCnt,1} = checkedNodes(i).Parent.Text;
                    tmp{cnCnt,2} =  str2double(checkedNodes(i).Text(isstrprop(checkedNodes(i).Text, 'digit')));
                    cnCnt = cnCnt+1;
                end
            end
            if(isempty(app.selectedFLIMItems))
                app.selectedFLIMItems = tmp;
                return
            end
            idxHit = false(size(app.selectedFLIMItems,1),1);
            for i = size(app.selectedFLIMItems,1):-1:1
                if(isempty(tmp))
                    break
                end
                for j = size(tmp,1):-1:1
                    if(strcmp(app.selectedFLIMItems(i,1),tmp(j,1)) && app.selectedFLIMItems{i,2} == tmp{j,2})
                        %FLIM items already in list -> delete it from tmp
                        idxHit(i,1) = true;
                        tmp(j,:) = [];
                        break
                    end
                end
            end
            %remove deleted FLIM items
            app.selectedFLIMItems(~idxHit,:) = [];
            %remaining items in tmp are new
            nNew = size(tmp,1);
            %append new FLIM items at the end
            app.selectedFLIMItems(end+1:end+nNew,:) = tmp;
        end

        % Button pushed function: ButtonAddGroup
        function ButtonAddGroupPushed(app, event)
            grpNr = size(app.grpDefs,1)+1;
            newGrp{1,1} = app.currentStudy;
            newGrp{1,2} = app.currentCondition;
            %find checked FLIM items (their channels)
            checkedNodes = app.selectedFLIMItems;
            if(isempty(checkedNodes))
                uialert(app.FLIMXStatsMultiGroupUIFigure,'Select at least one FLIM item!','Error');
                return
            end
            if(grpNr > 1 && size(app.grpDefs{1,3},1) ~= size(checkedNodes,1))
                uialert(app.FLIMXStatsMultiGroupUIFigure,sprintf('You have selected %d FLIM items but the first group consists of %d FLIM items.\n\nPlease select %d FLIM items to add a new group!',size(checkedNodes,1),length(app.grpDefs{1,3}),length(app.grpDefs{1,3})),'Error');
                return
            end
            newGrp{1,3} = checkedNodes;
            %get current ROI type
            ROIstr = app.DropDownROIType.Value;
            if(strncmp(ROIstr,'Group: ',7))
                %this is a group
                idx = strncmp(app.DropDownROIType.Items,'Group: ',7);
                grps = app.DropDownROIType.Items(idx);
                rt = -1*find(strcmp(grps,ROIstr),1,'first');
            else
                rt = ROICtrl.ROIItem2ROIType(ROIstr);
            end
            %get ROI subtype
            if(strcmp(app.DropDownROISubtype.Visible,'on'))
                rs = find(strcmp(app.DropDownROISubtype.Items,app.DropDownROISubtype.Value));
            else
                rs = 0;
            end
            %get vicinity
            if(strcmp(app.DropDownROIVicinity.Visible,'on'))
                vi = find(strcmp(app.DropDownROIVicinity.Items,app.DropDownROIVicinity.Value));
            else
                vi = 0;
            end
            newGrp{1,4} = [rt,rs,vi];
            %check if the new group already exists
            %             if(grpNr == 1 || ~any(strcmp(app.grpDefs(:,1),newGrp{1})) || ~any(strcmp(app.grpDefs(:,2),newGrp{2})) || ~all(all([app.grpDefs{:,4}] == newGrp{4})))
            %todo: check FLIM items
            app.grpDefs(grpNr,:) = newGrp;
            %             end
            app.updateGUI();
        end

        % Value changed function: DropDownROIType
        function DropDownROITypeValueChanged(app, event)
            app.updateGUI();
        end

        % Button pushed function: ButtonTest
        function ButtonTestPushed(app, event)
            app.TableExtAnalysis.Data = {};
            if(size(app.grpDefs,1) < 1)
                return
            end
            app.workingLamp.Visible = 'On';
            %app.workingLabel.Text = sprintf('<html><img src="file:/%s"/> work</html>',FLIMX.getAnimationPath());
            app.workingLabel.Visible = 'On';
            app.FLIMXStatsMultiGroupUIFigure.Pointer = 'watch';
            drawnow
            %             try
            %                 app.ButtonTest.Text = sprintf('<html><img src="file:/%s"/> Test</html>',FLIMX.getAnimationPath());
            %                 drawnow;
            %             end
            target = app.DropDownDataSource.Value;
            if(strcmp(target,'pixel value(s)'))
                target = 'raw';
            elseif(~strcmp(target,'mean') && ~strcmp(target,'median'))
                target = 'subject';
            end
            nFI = size(app.grpDefs{1,3},1);
            nGrps = size(app.grpDefs,1);
            meanVals = zeros(nGrps,nFI);
            medianVals = zeros(nGrps,nFI);
            sdVals = zeros(nGrps,nFI);
            nVals = zeros(nGrps,nFI);
            subGrpNames = cell(nGrps,nFI);
            pLevene = zeros(nGrps,1);
            tmp = cell(3,2);
            tmp{1,1} = 'Test';
            switch app.TestDropDown.Value
                case {'1-Way ANOVA'}
                    tmp{1,2} = 'One-way Analysis of Variance (ANOVA) to determine whether data from several groups have a common mean';
                    tmp{2,1} = 'Null Hypothesis';
                    tmp{2,2} = 'all group means are equal against the alternative hypothesis that at least one group is different from the others';
                    tmp{3,1} = 'Decision d';
                    tmp{3,2} = 'If d = 1, at least one of the sample means is significantly different from the others';
                case {'2-Way ANOVA'}
                    tmp{1,2} = 'Two-way Analysis of Variance (ANOVA) to determine whether data from several groups have a common mean';
                    tmp{2,1} = 'Null Hypothesis';
                    tmp{2,2} = 'all group means are equal against the alternative hypothesis that at least one group is different from the others';
                    tmp{3,1} = 'Decision d';
                    tmp{3,2} = 'If d = 1, at least one of the sample means is significantly different from the others';
                case {'n-Way ANOVA'}
                    tmp{1,2} = 'n-way Analysis of Variance (ANOVA) to determine whether data from several groups have a common mean';
                    tmp{2,1} = 'Null Hypothesis';
                    tmp{2,2} = 'all group means are equal against the alternative hypothesis that at least one group is different from the others';
                    tmp{3,1} = 'Decision d';
                    tmp{3,2} = 'If d = 1, at least one of the sample means is significantly different from the others';
                case {'Kruskal-Wallis'}
                    tmp{1,2} = 'Kruskal-Wallis is a non-parametric method for testing whether samples originate from the same distribution';
                    tmp{2,1} = 'Null Hypothesis';
                    tmp{2,2} = 'the data in each group / FLIM item comes from the same distribution against the alternative hypothesis that not all groups come from the same distribution';
                    tmp{3,1} = 'Decision d';
                    tmp{3,2} = 'If d = 1, at least one of the sample means is significantly different from the others';
                case {'1-Way MANOVA'}
                    tmp{1,2} = 'One-way Multivariate Analysis of Variance (MANOVA) for comparing the multivariate means';
                    tmp{2,1} = 'Null Hypothesis';
                    tmp{2,2} = 'Means of each group are the same n-dimensional multivariate vector, and that any difference observed in the sample X is due to random chance.';
                    tmp{3,1} = 'Decision d';
                    tmp{3,2} = 'An estimate of the dimension of the space containing the group means';
                    tmp{4,2} = 'If d = 0, there is no evidence to reject that hypothesis';
                    tmp{5,2} = 'If d = 1, then you can reject the null hypothesis at the significance level, but you cannot reject the hypothesis that the multivariate means lie on the same line';
                    tmp{6,2} = 'If d = 2, the multivariate means may lie on the same plane in n-dimensional space, but not on the same line';
                case {'2-Way MANOVA'}
                    tmp{1,2} = 'Two-way Multivariate Analysis of Variance (MANOVA) for comparing the multivariate means';
                    tmp{2,1} = 'Null Hypothesis';
                    tmp{2,2} = 'Means of each group are the same n-dimensional multivariate vector, and that any difference observed in the sample X is due to random chance.';
                    tmp{3,1} = 'Decision d';
                    tmp{3,2} = 'An estimate of the dimension of the space containing the group means';
                    tmp{4,2} = 'If d = 0, there is no evidence to reject that hypothesis';
                    tmp{5,2} = 'If d = 1, then you can reject the null hypothesis at the significance level, but you cannot reject the hypothesis that the multivariate means lie on the same line';
                    tmp{6,2} = 'If d = 2, the multivariate means may lie on the same plane in n-dimensional space, but not on the same line';
            end
            tmp(end+1,1) = cell(1,1);
            if(~strcmp(target,'subject'))
                grpData = [];
                for i = 1:nGrps
                    gd = app.grpDefs(i,:);
                    fi = gd{3};
                    fiData = cell(0,0);
                    for j = 1:size(fi,1)
                        [dType, dTypeNr] = FLIMXVisGUI.FLIMItem2TypeAndID(fi{j,1});
                        if(strcmp(target,'raw'))
                            fiData{j,1} = app.fdt.getStudyPayload(gd{1},gd{2},fi{j,2},dType{1},dTypeNr,gd{4}(1),gd{4}(2),gd{4}(3),'raw');
                        else
                            %mean or median
                            [stats, statsDesc, subjectDesc] = app.fdt.getStudyStatistics(gd{1},gd{2},fi{j,2},dType{1},dTypeNr,gd{4}(1),gd{4}(2),gd{4}(3),true);
                            idx = strcmpi(statsDesc,target);
                            fiData{j,1} = stats(:,idx);
                            fiData{j,2} = subjectDesc;
                        end
                        meanVals(i,j) = mean(fiData{j,1},'omitnan');
                        medianVals(i,j) = median(fiData{j,1},'omitnan');
                        sdVals(i,j) = std(fiData{j,1},'omitnan');
                        nVals(i,j) = numel(fiData{j,1});
                        tStr = sprintf('Grp_%d_%sCh%d',i,fi{j,1},fi{j,2});
                        subGrpNames{i,j} = tStr(~isspace(tStr));
                        vt.(subGrpNames{i,j}) = fiData{j,1};
                    end
                    %convert fiData to numeric
                    if(strcmp(target,'raw'))
                        dm = min(cellfun(@length,fiData(:,1)));
                        mat = zeros(dm,size(fi,1)+1);
                        for j = 1:size(fi,1)
                            mat(:,j) = fiData{j}(1:dm);
                        end
                    else
                        dm = max(cellfun(@length,fiData(:,1)));
                        mat = zeros(dm,size(fi,1)+1);
                        subNames = fiData{1,2};
                        for j = 1:size(fi,1)
                            [subNames, idxMerge, idxNew] = intersect(subNames,fiData{j,2});
                            mat = mat(idxMerge,:);
                            mat(:,j) = fiData{j,1}(idxNew);
                        end
                    end
                    mat(:,end) = i;
                    switch app.TestDropDown.Value
                        case {'1-Way MANOVA','2-Way MANOVA'}
                            grpData = [grpData; mat;];
                        otherwise
                            if(~isempty(grpData))
                                grpData(:,end) = [];
                                if(size(grpData,1) > size(mat,1))
                                    grpData = grpData(1:size(mat,1),:);
                                    %grpData(1:size(mat,1),size(grpData,2)+1:size(grpData,2)+size(mat,2)) = mat;
                                elseif(size(grpData,1) < size(mat,1))
                                    mat = mat(1:size(grpData,1),:);
                                end
                            end
                            grpData = [grpData, mat];
                    end
                    clear mat
                end
                tmp = app.computeTest(grpData,tmp);
                if(nGrps > 1)
                    MBox = MBoxtest(circshift(grpData,1,2),app.alpha);
                    tmp{end+2,1} = 'MBox Test';
                    tmp{end,2} = sprintf('p = %0.3f (test for homogeneity of covariances matrices)',MBox.p);
                else
                    %check homogeneity of variances
                    tmp{end+2,1} = 'Levene Test';
                    tmp{end,2} = sprintf('p = %0.3f (test for equal of variances)',vartestn(grpData(:,1:end-1),'TestType','LeveneAbsolute','Display','off'));
                end
                meanVals = FLIMXFitGUI.num4disp(meanVals);
                medianVals = FLIMXFitGUI.num4disp(medianVals);
                sdVals = FLIMXFitGUI.num4disp(sdVals);
                if(nGrps == 1) %ANOVA
                    tmp{end+2,2} = sprintf('Mean %s standard deviation',char(177));
                end
                for j = 1:nFI
                    if(nGrps > 1)
                        tmp{end+2,1} = sprintf('FLIM item %d',j);
                        tmp{end,2} = sprintf('Mean %s standard deviation',char(177));
                    end
                    for i = 1:nGrps
                        tmp{end+1,1} = sprintf('%s Ch%d Grp %d (%d)',app.grpDefs{i,3}{j,1},app.grpDefs{i,3}{j,2},i,nVals(i,j));
                        tmp{end,2} = sprintf('%s %s %s',meanVals{i,j},char(177),sdVals{i,j});
                    end
                    if(nGrps > 1)
                        %check homogeneity of variances
                        tmp{end+1,1} = sprintf('Levene Test FLIM Item %d',j);
                        tmp{end,2} = sprintf('p = %0.3f (test for equal of variances)',vartestn(grpData(:,j),grpData(:,end),'TestType','LeveneAbsolute','Display','off'));
                        [pANOVA,tbl,stats] = anova1(grpData(:,j),grpData(:,end),'off');
                        tmp{end+1,1} = sprintf('ANOVA FLIM Item %d',j);
                        tmp{end,2} = sprintf('p = %0.3f (post-hoc one-way ANOVA)',pANOVA);
                        if(pANOVA < app.alpha)
                            [c,m,h,gnames] = multcompare(stats);
                            tmp{end+1,1} = 'post-hoc t-tests';
                            tmp{end,2} = sprintf('threshold = %0.4f (Bonferroni corrected)',app.alpha ./ size(c,1));
                            for m = 1:size(c,1)
                                tmp{end+1,1} = sprintf('%s Ch%d vs. %s Ch%d',app.grpDefs{c(m,1),3}{j,1},app.grpDefs{c(m,1),3}{j,2},app.grpDefs{c(m,2),3}{j,1},app.grpDefs{c(m,2),3}{j,2});
                                tmp{end,2} = sprintf('p = %0.4f',c(m,6));
                            end
                        end
                    end
                    %tmp(end+1,1) = cell(1,1);
                end
                app.AxesGrpPlots.Visible = 'On';
                app.AxesGrpPlots.TickLabelInterpreter = 'none';
                app.AxesGrpPlots.YLabel.String = '';
                cla(app.AxesGrpPlots);
                preColor = [146 208 80]./255;
                controlColor = [0 176 80]./255;
                provocColor = [255 0 0]./255;
                %grpColors = [repmat(preColor,4,1); repmat(controlColor,4,1); repmat(provocColor,4,1);];
                %grpColors = repmat([preColor;controlColor;provocColor] ,4,1);
                grpColors = repmat([preColor] ,4,1);
                nantest = structfun(@(x) all(isnan(x)),vt,'UniformOutput',false);
                eidx = find(struct2array(nantest));
                if(~isempty(eidx))
                    uialert(app.FLIMXStatsMultiGroupUIFigure,sprintf('Group %d is empty (no ROI defined?)!',eidx(1)),'Error');
                    app.workingLamp.Visible = 'Off';
                    app.workingLabel.Visible = 'Off';
                    app.FLIMXStatsMultiGroupUIFigure.Pointer = 'arrow';
                    return                    
                end
                violinplot(vt, [], app.AxesGrpPlots, grpColors, 'ShowMean',true,'ViolinAlpha',1);
            else
                gd = app.grpDefs(1,:);
                subNames = app.fdt.getAllSubjectNames(gd{1},gd{2});
                nSubjects = length(subNames);
                %                 this.updateLongProgress(0.01,'0.0%% - ETA: -');
                %                 tStart = clock;
                results = cell(nSubjects,3);
                grpData = [];
                for k = 1:length(subNames)
                    meanVals = zeros(nGrps,nFI);
                    medianVals = zeros(nGrps,nFI);
                    sdVals = zeros(nGrps,nFI);
                    nVals = zeros(nGrps,nFI);
                    for i = 1:nGrps
                        gd = app.grpDefs(i,:);
                        fi = gd{3};
                        fiData = cell(0,0);
                        for j = 1:size(fi,1)
                            [dType, dTypeNr] = FLIMXVisGUI.FLIMItem2TypeAndID(fi{j,1});
                            hFD = app.fdt.getFDataObj(gd{1},subNames{k},fi{j,2},dType{1},dTypeNr,1);
                            if(isempty(hFD))
                                fiData{j,1} = [];
                                break
                            end
                            rc = hFD.getROICoordinates(gd{4}(1));
                            if(isempty(rc) && any(gd{4}) &&  ~any(gd{4} < 0)) %exclude ROI groups
                                %specific roi requested but no roi defined
                                fiData{j,1} = [];
                                break
                            end
                            [img, idx] = hFD.getROIImage(rc,gd{4}(1),gd{4}(2),gd{4}(3));
                            nanIdx = find(isnan(img(:)));
                            idx = setdiff(idx,nanIdx);
                            if(isempty(img) || isempty(idx))
                                fiData{j,1} = [];
                                break
                            end
                            fiTmp = hFD.getFullImage();
                            img = fiTmp(idx);
                            fiData{j,1} = fiTmp(:);
                            fiData{j,2} = idx;
                            meanVals(i,j) = mean(img(:),'omitnan');
                            medianVals(i,j) = median(img(:),'omitnan');
                            sdVals(i,j) = std(img(:),'omitnan');
                            nVals(i,j) = numel(img(:));
                            tStr = sprintf('Grp_%d_%s',i,fi{j,1});
                            subGrpNames{i,j} = tStr(~isspace(tStr));
                            vt.(subGrpNames{i,j}) = fiData{j,1};
                        end
                        if(any(cellfun(@isempty,fiData(:,1))))
                            continue
                        end
                        pixelIdx = unique(cell2mat(fiData(:,2)));
                        myIntersect = @(x) intersect(x,pixelIdx);
                        fiData(:,2) = cellfun(myIntersect,fiData(:,2),'UniformOutput',false);
                        minLen = cellfun(@length,fiData(:,2));
                        [~,idxLen] = min(minLen);
                        pixelIdx = fiData{idxLen,2};
                        mat = zeros(length(pixelIdx),size(fi,1)+1);
                        for j = 1:size(fi,1)
                            idx = ismember(fiData{j,2},pixelIdx);
                            mat(idx,j) = fiData{j,1}(idx);
                        end
                        mat(:,end) = i;
                        switch app.TestDropDown.Value
                            case {'1-Way MANOVA','2-Way MANOVA'}
                                grpData = [grpData; mat;];
                            otherwise
                                if(~isempty(grpData))
                                    grpData(:,end) = [];
                                    if(size(grpData,1) > size(mat,1))
                                        grpData = grpData(1:size(mat,1),:);
                                        %grpData(1:size(mat,1),size(grpData,2)+1:size(grpData,2)+size(mat,2)) = mat;
                                    elseif(size(grpData,1) < size(mat,1))
                                        mat = mat(1:size(grpData,1),:);
                                    end
                                end
                                grpData = [grpData, mat];
                        end
                        clear mat
                    end
                    if(isempty(grpData))
                        continue
                    end
                    %[results{k,1},results{k,2},results{k,3}] = manova1(grpData(:,1:end-1),grpData(:,end),app.alpha);
                    tmp{end+1,1} = 'Subject';
                    tmp(end,2) = subNames(k);
                    tmp = app.computeTest(grpData,tmp);
                    if(nGrps > 1)
                        MBox = MBoxtest(circshift(grpData,1,2),app.alpha);
                        tmp{end+2,1} = 'MBox Test';
                        tmp{end,2} = sprintf('p = %0.3f (test for homogeneity of covariances matrices)',MBox.p);
                    else
                        %only one group: ANOVA
                        %check homogeneity of variances
                        tmp{end+2,1} = 'Levene Test';
                        tmp{end,2} = sprintf('p = %0.3f (test for equal of variances)',vartestn(grpData(:,1:end-1),'TestType','LeveneAbsolute','Display','off'));
                    end
                    meanVals = FLIMXFitGUI.num4disp(meanVals);
                    medianVals = FLIMXFitGUI.num4disp(medianVals);
                    sdVals = FLIMXFitGUI.num4disp(sdVals);
                    if(nGrps == 1) %ANOVA
                        tmp{end+2,2} = sprintf('Mean %s standard deviation',char(177));
                    end
                    for j = 1:nFI
                        if(nGrps > 1)
                            tmp{end+2,1} = sprintf('FLIM item %d',j);
                            tmp{end,2} = sprintf('Mean %s standard deviation',char(177));
                        end
                        for i = 1:nGrps
                            tmp{end+1,1} = sprintf('%s Ch%d (%d)',subGrpNames{i,j},app.grpDefs{i,3}{j,2},nVals(i,j));
                            tmp{end,2} = sprintf('%s %s %s',meanVals{i,j},char(177),sdVals{i,j});
                        end
                        if(nGrps > 1)
                            %check homogeneity of variances
                            tmp{end+1,1} = sprintf('Levene Test FLIM Item %d',j);
                            tmp{end,2} = sprintf('p = %0.3f (test for equal of variances)',vartestn(grpData(:,j),grpData(:,end),'TestType','LeveneAbsolute','Display','off'));
                            [pANOVA,tbl,stats] = anova1(grpData(:,j),grpData(:,end),'off');
                            tmp{end+1,1} = sprintf('ANOVA FLIM Item %d',j);
                            tmp{end,2} = sprintf('p = %0.3f (post-hoc one-way ANOVA)',pANOVA);
                            if(pANOVA < app.alpha)
                                [c,m,h,gnames] = multcompare(stats);
                                tmp{end+1,1} = 'post-hoc t-tests';
                                tmp{end,2} = sprintf('threshold = %0.4f (Bonferroni corrected)',app.alpha ./ size(c,1));
                                for m = 1:size(c,1)
                                    tmp{end+1,1} = sprintf('%s Ch%d vs. %s Ch%d',app.grpDefs{c(m,1),3}{j,1},app.grpDefs{c(m,1),3}{j,2},app.grpDefs{c(m,2),3}{j,1},app.grpDefs{c(m,2),3}{j,2});
                                    tmp{end,2} = sprintf('p = %0.4f',c(m,6));
                                end
                            end
                        end
                        %tmp(end+1,1) = cell(1,1);
                    end
                    tmp(end+1,1) = cell(1,1);
                end
            end
            app.TableExtAnalysis.Data = tmp;
            app.TableExtAnalysis.ColumnWidth = {180 320};
            try
                close(app.manovaDummyFig);
            end
            app.workingLamp.Visible = 'Off';
            app.workingLabel.Visible = 'Off';
            app.FLIMXStatsMultiGroupUIFigure.Pointer = 'arrow';
        end

        % Button pushed function: ButtonDeleteGroup
        function ButtonDeleteGroupPushed(app, event)
            if(isempty(app.selectedGrps))
                return
            end
            app.selectedGrps(app.selectedGrps > size(app.grpDefs,1)) = [];
            app.grpDefs(app.selectedGrps,:) = [];
            app.selectedGrps = [];
            app.updateGUI();
        end

        % Cell selection callback: TableGroups
        function TableGroupsCellSelection(app, event)
            app.selectedGrps = unique(event.Indices(:,1));
        end

        % Selection changed function: TreeFLIMItems
        function TreeFLIMItemsSelectionChanged(app, event)
            selectedNodes = app.TreeFLIMItems.SelectedNodes;
            selectedNodes.expand();
        end

        % Button pushed function: ButtonDeleteAllGroups
        function ButtonDeleteAllGroupsPushed(app, event)
            uiconfirm(app.FLIMXStatsMultiGroupUIFigure,'Delete all group definitons?','Confirm Delete Groups', 'CloseFcn',@app.deleteAllGroups);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FLIMXStatsMultiGroupUIFigure = uifigure('Visible', 'off');
            app.FLIMXStatsMultiGroupUIFigure.Name = 'FLIMX Multiple Groups Comparison Statistics';
            app.AxesGrpPlots = uiaxes(app.FLIMXStatsMultiGroupUIFigure);
            zlabel(app.AxesGrpPlots, 'Z')
            app.AxesGrpPlots.Visible = 'off';
            app.DropDownStudy = uidropdown(app.FLIMXStatsMultiGroupUIFigure);
            app.DropDownStudy.ValueChangedFcn = createCallbackFcn(app, @DropDownStudyValueChanged, true);
            app.DropDownCondition = uidropdown(app.FLIMXStatsMultiGroupUIFigure);
            app.DropDownCondition.Tag = 'DropDownCondition';
            app.ButtonTest = uibutton(app.FLIMXStatsMultiGroupUIFigure, 'push');
            app.ButtonTest.ButtonPushedFcn = createCallbackFcn(app, @ButtonTestPushed, true);
            app.ButtonTest.Text = 'Test';
            app.DatasourceDropDownLabel = uilabel(app.FLIMXStatsMultiGroupUIFigure);
            app.DatasourceDropDownLabel.Text = 'Data source';
            app.DropDownDataSource = uidropdown(app.FLIMXStatsMultiGroupUIFigure);
            app.DropDownDataSource.Items = {'pixel value(s)', 'mean', 'median', 'test for each subject (pixel values)'};
            app.DropDownDataSource.Tag = 'DropDownDataSource';
            app.DropDownDataSource.Value = 'pixel value(s)';
            app.DropDownROIType = uidropdown(app.FLIMXStatsMultiGroupUIFigure);
            app.DropDownROIType.ValueChangedFcn = createCallbackFcn(app, @DropDownROITypeValueChanged, true);
            app.DropDownROIType.Tag = 'DropDownROI';
            app.TreeFLIMItems = uitree(app.FLIMXStatsMultiGroupUIFigure, 'checkbox');
            app.TreeFLIMItems.SelectionChangedFcn = createCallbackFcn(app, @TreeFLIMItemsSelectionChanged, true);
            % Assign Checked Nodes
            app.TreeFLIMItems.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeFLIMItemsCheckedNodesChanged, true);
            app.ButtonAddGroup = uibutton(app.FLIMXStatsMultiGroupUIFigure, 'push');
            app.ButtonAddGroup.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddGroupPushed, true);
            app.ButtonAddGroup.Text = 'Add group';
            app.TableGroups = uitable(app.FLIMXStatsMultiGroupUIFigure);
            app.TableGroups.ColumnName = {'Group'; 'Study'; 'Condition'; 'FLIM Items'; 'ROI'};
            app.TableGroups.RowName = {};
            app.TableGroups.CellSelectionCallback = createCallbackFcn(app, @TableGroupsCellSelection, true);
            app.TableExtAnalysis = uitable(app.FLIMXStatsMultiGroupUIFigure);
            app.TableExtAnalysis.ColumnName = '';
            app.TableExtAnalysis.RowName = {};
            app.DropDownROISubtype = uidropdown(app.FLIMXStatsMultiGroupUIFigure);
            app.DropDownROISubtype.Items = {'Central', 'Inner Superior', 'Inner Nasal', 'Inner Inferior', 'Inner Temporal', 'Outer Superior', 'Outer Nasal', 'Outer Inferior', 'Outer Temporal', 'Inner Ring', 'Outer Ring', 'Full Circle', 'Center + Inner Ring', 'Center + Outer Ring', 'Inner + Outer Ring'};
            app.DropDownROISubtype.Tag = 'DropDownROI';
            app.DropDownROISubtype.Value = 'Central';
            app.DropDownROIVicinity = uidropdown(app.FLIMXStatsMultiGroupUIFigure);
            app.DropDownROIVicinity.Items = {'Inside', 'Outside', 'Vicinity'};
            app.DropDownROIVicinity.Tag = 'DropDownROI';
            app.DropDownROIVicinity.Value = 'Inside';
            app.ButtonDeleteGroup = uibutton(app.FLIMXStatsMultiGroupUIFigure, 'push');
            app.ButtonDeleteGroup.ButtonPushedFcn = createCallbackFcn(app, @ButtonDeleteGroupPushed, true);
            app.ButtonDeleteGroup.Text = 'Delete selected';
            app.SignificancelevelEditFieldLabel = uilabel(app.FLIMXStatsMultiGroupUIFigure);
            app.SignificancelevelEditFieldLabel.Text = 'Significance level';
            app.SignificancelevelEditField = uieditfield(app.FLIMXStatsMultiGroupUIFigure, 'numeric');
            app.SignificancelevelEditField.Limits = [0 100];
            app.SignificancelevelEditField.ValueDisplayFormat = '%0.1f';
            app.SignificancelevelEditField.Value = 5;
            app.SignificancelevelEditFieldAlpha = uilabel(app.FLIMXStatsMultiGroupUIFigure);
            app.SignificancelevelEditFieldAlpha.Text = '(a)';
            app.SignificancelevelEditFieldPercent = uilabel(app.FLIMXStatsMultiGroupUIFigure);
            app.SignificancelevelEditFieldPercent.Text = '%';
            app.workingLabel = uilabel(app.FLIMXStatsMultiGroupUIFigure);
            app.workingLabel.Visible = 'off';
            app.workingLabel.Text = 'working...';
            app.TestDropDownLabel = uilabel(app.FLIMXStatsMultiGroupUIFigure);
            app.TestDropDownLabel.Text = 'Test';
            app.TestDropDown = uidropdown(app.FLIMXStatsMultiGroupUIFigure);
            app.TestDropDown.Items = {'1-Way MANOVA'};
            app.TestDropDown.Value = '1-Way MANOVA';
            app.workingLamp = uiimage(app.FLIMXStatsMultiGroupUIFigure);
            app.workingLamp.ImageSource = 'spinner.gif';
            app.ButtonDeleteAllGroups = uibutton(app.FLIMXStatsMultiGroupUIFigure, 'push');
            app.ButtonDeleteAllGroups.ButtonPushedFcn = createCallbackFcn(app, @ButtonDeleteAllGroupsPushed, true);
            app.ButtonDeleteAllGroups.Text = 'Delete all';
            % Show the figure after all components are created
            app.FLIMXStatsMultiGroupUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StatsMultiGroupComparison(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FLIMXStatsMultiGroupUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FLIMXStatsMultiGroupUIFigure)
        end
    end
end